classdef GPIB_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        DMMFuncButtonGroup         matlab.ui.container.ButtonGroup
        ContButton                 matlab.ui.control.RadioButton
        TempButton                 matlab.ui.control.RadioButton
        R4WireButton               matlab.ui.control.RadioButton
        R2WireButton               matlab.ui.control.RadioButton
        IACButton                  matlab.ui.control.RadioButton
        IDCButton                  matlab.ui.control.RadioButton
        VACButton                  matlab.ui.control.RadioButton
        VDCButton                  matlab.ui.control.RadioButton
        VDC___Button               matlab.ui.control.Button
        Keithley2700connectButton  matlab.ui.control.Button
        HP4192AconnectButton       matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        addrHP4192A = 1;
        addrKeithley2700 = 8;
        addrIL800 = 16; % Description
        timer % Description
   end
    
    methods (Access = private)
        function startTimer(app)
            app.timer = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(~,~)myFunction(app));
            start(app.timer);
        end

    function myFunction(app)
        % Hier wird deine Funktion aufgerufen
        disp('Funktion wird zyklisch aufgerufen');
    end
    
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % init GPIB Controller
            global myGpib;
            myGpib = gpibClass;
            % reset interface
            myGpib.init();
            startTimer(app);
          
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: HP4192AconnectButton
        function HP4192AconnectButtonPushed(app, event)
            global hRCLmeter;
            hRCLmeter = myGpib.connect(app.addrHP4192A);
        end

        % Button pushed function: Keithley2700connectButton
        function Keithley2700connectButtonPushed(app, event)
            global hDmm;
            global dmm;
            global myGpib;
            hDmm = myGpib.connect(app.addrKeithley2700);        
            dmm = Keithley2700(hDmm);
            dmm.reset;
        end

        % Button pushed function: VDC___Button
        function VDC___ButtonPushed(app, event)
            global dmm;
            dmm.setVDC;
            dmm.send('INIT:CONT 1');
        end

        % Selection changed function: DMMFuncButtonGroup
        function DMMFuncButtonGroupSelectionChanged(app, event)
            global dmm;
            selectedButton = char(app.DMMFuncButtonGroup.SelectedObject.Tag);
            disp(selectedButton);
                switch selectedButton
                case 'VDC'
                    dmm.setVDC;
                case 'VAC'
                    dmm.setVAC;
                case 'IDC'
                    dmm.setIDC;
                case 'IAC'
                    dmm.setIAC;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HP4192AconnectButton = uibutton(app.LeftPanel, 'push');
            app.HP4192AconnectButton.ButtonPushedFcn = createCallbackFcn(app, @HP4192AconnectButtonPushed, true);
            app.HP4192AconnectButton.Text = 'HP4192A connect';
            app.Keithley2700connectButton = uibutton(app.LeftPanel, 'push');
            app.Keithley2700connectButton.ButtonPushedFcn = createCallbackFcn(app, @Keithley2700connectButtonPushed, true);
            app.Keithley2700connectButton.Text = 'Keithley2700 connect';
            app.VDC___Button = uibutton(app.LeftPanel, 'push');
            app.VDC___Button.ButtonPushedFcn = createCallbackFcn(app, @VDC___ButtonPushed, true);
            app.VDC___Button.Text = {'VDC___'; ''};
            app.DMMFuncButtonGroup = uibuttongroup(app.LeftPanel);
            app.DMMFuncButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DMMFuncButtonGroupSelectionChanged, true);
            app.DMMFuncButtonGroup.Title = 'DMM Func';
            app.VDCButton = uiradiobutton(app.DMMFuncButtonGroup);
            app.VDCButton.Tag = 'VDC';
            app.VDCButton.Text = 'VDC';
            app.VDCButton.Value = true;
            app.VACButton = uiradiobutton(app.DMMFuncButtonGroup);
            app.VACButton.Tag = 'VAC';
            app.VACButton.Text = 'VAC';
            app.IDCButton = uiradiobutton(app.DMMFuncButtonGroup);
            app.IDCButton.Tag = 'IDC';
            app.IDCButton.Text = 'IDC';
            app.IACButton = uiradiobutton(app.DMMFuncButtonGroup);
            app.IACButton.Tag = 'IAC';
            app.IACButton.Text = 'IAC';
            app.R2WireButton = uiradiobutton(app.DMMFuncButtonGroup);
            app.R2WireButton.Text = 'R 2Wire';
            app.R4WireButton = uiradiobutton(app.DMMFuncButtonGroup);
            app.R4WireButton.Text = 'R 4Wire';
            app.TempButton = uiradiobutton(app.DMMFuncButtonGroup);
            app.TempButton.Text = 'Temp';
            app.ContButton = uiradiobutton(app.DMMFuncButtonGroup);
            app.ContButton.Text = 'Cont';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GPIB_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end